classdef APPLICAZIONE_PROGETTO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Progetto_EIMUIFigure            matlab.ui.Figure
        OriginalImage                   matlab.ui.control.Image
        CaricamentoButton               matlab.ui.control.Button
        NormalizedImage                 matlab.ui.control.Image
        ImmagineOriginaleLabel          matlab.ui.control.Label
        ImmagineNormalizzataLabel       matlab.ui.control.Label
        SuccessivaButton                matlab.ui.control.Button
        PrecedenteButton                matlab.ui.control.Button
        FilevisualizzatoEditFieldLabel  matlab.ui.control.Label
        FilevisualizzatoEditField       matlab.ui.control.EditField
        TargetImage                     matlab.ui.control.Image
        ImmagineTargetLabel             matlab.ui.control.Label
        NormalizzaButton                matlab.ui.control.Button
        CalcolarSEButton                matlab.ui.control.Button
        RSEField                        matlab.ui.control.NumericEditField
        SalvataggioImmagineNormalizzataButton  matlab.ui.control.Button
        FileNormalizzatoEditFieldLabel  matlab.ui.control.Label
        FileNormalizzatoEditField       matlab.ui.control.EditField
        CaricaTargetButton              matlab.ui.control.Button
        FileTargetEditFieldLabel        matlab.ui.control.Label
        FileTargetEditField             matlab.ui.control.EditField
        SalvataggiorSEImmagineNormalizzataButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        ButtonGroup                     matlab.ui.container.ButtonGroup
        txtButton                       matlab.ui.control.RadioButton
        xlsButton                       matlab.ui.control.RadioButton
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = public)
        DataStorage;
        I_norm; I_normStorage;
        cpt;
        files,path;
        files2,path2;
        type;
        storage;
        RseStorage;
        flagsalvataggiosingleimg;
        flagsalvataggiorsesingle;
        flagsalvataggiobatchimg;
        flagsalvataggiobatchrse;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, a)
            % clear all
            close all
            clc
            app.cpt=0;
            
            app.PrecedenteButton.Enable='off';
            app.SuccessivaButton.Enable='off';
            app.NormalizzaButton.Enable='off';
            app.CalcolarSEButton.Enable='off';
            app.SalvataggioImmagineNormalizzataButton.Enable='off';
            app.CaricamentoButton.Enable='off';
            app.SalvataggiorSEImmagineNormalizzataButton.Enable='off';
            app.xlsButton.Enable='off';
            app.txtButton.Enable='off';
            app.UITable.Visible='off';
            app.RSEField.Enable='off';
            message=sprintf('App per normalizzazione immagini immunoistochimiche con Diaminobenzidina ed Ematossilina');
            uialert(app.Progetto_EIMUIFigure,message,'Benvenuti','Icon','info');
            app.flagsalvataggiosingleimg=0;
            app.flagsalvataggiorsesingle=0;
            app.flagsalvataggiobatchimg=0;
            app.flagsalvataggiobatchrse=0;
        end

        % Button pushed function: CaricamentoButton
        function CaricamentoButtonPushed(app, event)
            
            if ~app.Switch.Value %single mode
                [app.files,app.path]=uigetfile('*','Load',"MultiSelect","on");
                %dare indietro errore
                app.type=isa(app.files,'char');
                
                if app.type
                    app.OriginalImage.ImageSource=imread(strcat(app.path,'\',app.files));
                    app.DataStorage{1,1}=char(app.files);
                    app.DataStorage{1,2}=imread(strcat(app.path,'\',app.files));
                    app.FilevisualizzatoEditField.Value=app.files;
                    app.PrecedenteButton.Enable='off';
                    app.SuccessivaButton.Enable='off';
                else
                    app.PrecedenteButton.Enable='on';
                    app.SuccessivaButton.Enable='on';
                    
                    for i=1:length(app.files)
                        app.DataStorage{i,1}=char(app.files(i));
                        app.DataStorage{i,2}=imread(strcat(app.path,app.files{i}));
                    end
                    
                    app.OriginalImage.ImageSource=cell2mat(app.DataStorage(1,2));
                    app.FilevisualizzatoEditField.Value=char(app.DataStorage(1,1));
                end
            else %batch mode
                [app.storage]=batchmode(app.files2);
                app.UITable.Data=app.storage;
            end
            app.NormalizzaButton.Enable='on';
            
        end

        % Button pushed function: SuccessivaButton
        function SuccessivaButtonPushed(app, event)
            app.cpt=app.cpt+1;
            if app.cpt > length(app.files)
                app.cpt=1;
            end
            app.OriginalImage.ImageSource=cell2mat(app.DataStorage(app.cpt,2));
            app.FilevisualizzatoEditField.Value=char(app.DataStorage(app.cpt,1));
            app.NormalizzaButton.Enable='on';
        end

        % Button pushed function: PrecedenteButton
        function PrecedenteButtonPushed(app, event)
            app.cpt=app.cpt-1;
            if app.cpt < 1
                app.cpt=length(app.files);
            end
            app.OriginalImage.ImageSource=cell2mat(app.DataStorage(app.cpt,2));
            app.FilevisualizzatoEditField.Value=char(app.DataStorage(app.cpt,1));
            app.NormalizzaButton.Enable='on';
        end

        % Button pushed function: NormalizzaButton
        function NormalizzaButtonPushed(app, event)
            fig=app.Progetto_EIMUIFigure;
            d=uiprogressdlg(fig,'Title','Normalizzazione','Message','Inizializzazione...');d.Value=0;
                pause(0.5)
            if app.Switch.Value %batch mode
                d.Message='Caricamento...'; d.Value=0.01;
                pause(0.5)
                for i=1:length(app.storage)
                    mex=sprintf('Calcolo in corso...%d/%d',i,length(app.storage));
                    d.Message=mex; d.Value=i/length(app.storage);
                    [I_norm]=NHIs(app.storage{i,1},app.storage{i,2},char(app.files2),app.path2);
                    app.I_normStorage{i}=I_norm;
                    app.RseStorage{i}=rSE_GUI(app.I_normStorage{i},app.storage{i,1},app.storage{i,2},app.files2,app.path2);
                end
                d.Message='Finalizzazione'; d.Value=0.99;
                pause(0.5)
                close(d)
                app.SalvataggiorSEImmagineNormalizzataButton.Enable='on';
                app.xlsButton.Enable='on';
                app.txtButton.Enable='on';
                app.flagsalvataggiobatchimg=0;
                app.flagsalvataggiobatchrse=0;
            else %single mode
                d.Message='Caricamento...'; d.Value=0.33;
                pause(0.5)
                d.Message='Calcolo in corso...'; d.Value=0.67;
                if app.cpt==0
                    [app.I_norm]=NHIs(char(app.files),app.path,char(app.files2),app.path2);
                    s=app.FilevisualizzatoEditField.Value;
                    app.FileNormalizzatoEditField.Value=strcat(s);
                else
                    [app.I_norm]=NHIs(char(app.files(app.cpt)),app.path,char(app.files2),app.path2);
                    s=app.FilevisualizzatoEditField.Value;
                    app.FileNormalizzatoEditField.Value=strcat(s);
                end
                d.Message='Finalizzazione...'; d.Value=0.99;
                pause(0.5)
                close(d)
                
                app.NormalizedImage.ImageSource=app.I_norm;
                app.CalcolarSEButton.Enable='on';
                app.xlsButton.Enable='off';
                app.txtButton.Enable='off';
                app.SalvataggiorSEImmagineNormalizzataButton.Enable='off';
                app.PrecedenteButton.Enable='off';
                app.SuccessivaButton.Enable='off';
                
                app.flagsalvataggiosingleimg=0;
                app.flagsalvataggiorsesingle=0;
            end
            
            app.NormalizzaButton.Enable='off';
            app.RSEField.Value=0;
            app.SalvataggioImmagineNormalizzataButton.Enable='on';
        end

        % Button pushed function: 
        % SalvataggioImmagineNormalizzataButton
        function SalvataggioImmagineNormalizzataButtonPushed(app, event)
            if app.Switch.Value %batch mode
                d=uigetdir('scegli cartella dove salvare');
                for i=1:length(app.storage)
                    s=app.storage{i,1};
                    split=strsplit(s,'.');
                    name=sprintf('%s_normalizzato.jpg',split{1});
                    imwrite(app.I_normStorage{i},strcat(d,'\',name));
                end
                app.flagsalvataggiobatchimg=1;
                message=sprintf('Immagini correttamente salvate nella cartella');
            else %single mode
                s=app.FileNormalizzatoEditField.Value;
                split=strsplit(s,'.');
                name=sprintf('%s_normalizzato.jpg',split{1});
                [file,path,indx] = uiputfile(name);
                if indx
                    imwrite(app.I_norm,strcat(path,file));
                end
                app.flagsalvataggiosingleimg=1;
                message=sprintf('Immagine correttamente salvata nella cartella');
            end
            %app.SalvataggioImmagineNormalizzataButton.Enable='off';
            %inserire messaggio di conferma
        
            uialert(app.Progetto_EIMUIFigure,message,'Salvataggio','Icon','info');
        end

        % Button pushed function: CalcolarSEButton
        function CalcolarSEButtonPushed(app, event)
            if app.type
                app.RSEField.Value=rSE_GUI(app.I_norm,char(app.DataStorage(1,1)),app.path,char(app.files2),app.path2);
            else
                app.RSEField.Value=rSE_GUI(app.I_norm,char(app.DataStorage(app.cpt,1)),app.path,char(app.files2),app.path2);
            end
            app.CalcolarSEButton.Enable='off';
            app.SalvataggiorSEImmagineNormalizzataButton.Enable='on';
            app.xlsButton.Enable='on';
            app.txtButton.Enable='on';
            app.RSEField.Enable='on';
        end

        % Button pushed function: CaricaTargetButton
        function CaricaTargetButtonPushed(app, event)
            [app.files2,app.path2]=uigetfile('*','Load');
            app.TargetImage.ImageSource=imread(strcat(app.path2,'\',app.files2));
            app.FileTargetEditField.Value=app.files2;
            app.CaricamentoButton.Enable='on';
        end

        % Button pushed function: 
        % SalvataggiorSEImmagineNormalizzataButton
        function SalvataggiorSEImmagineNormalizzataButtonPushed(app, event)
            if app.Switch.Value %batch mode
                d=uigetdir('scegli cartella dove salvare');
                if app.xlsButton.Value
                    
                    name=sprintf('rSE.xls');
                    Rse=app.RseStorage;
                    nameimages=app.storage(:,1);
                    xlswrite(strcat(d,'\',name),Rse',1,'B1');
                    xlswrite(strcat(d,'\',name),string(nameimages),1,'A1');
                else
                    
                    name=sprintf('rSE.txt');
                    fp=fopen(strcat(d,'\',name),'w');
                    for i=1:length(app.RseStorage)
                        tmp(i)=strcat(app.storage{i,1},{'  '},string(app.RseStorage{i}),{'  '});
                    end
                    fprintf(fp,'%s',string(tmp));
                    fclose(fp);
                end
                app.flagsalvataggiobatchrse=1;
            else %single mode
                s=app.FileNormalizzatoEditField.Value;
                if app.xlsButton.Value
                    split=strsplit(s,'.');
                    name=sprintf('rSE_%s.xls',split{1});
                    [filename,path,~] = uiputfile(name);
                    Rse=app.RSEField.Value;
                    xlswrite(strcat(path,filename),[string(s),""],1,'A1');
                    xlswrite(strcat(path,filename),Rse',1,'B1');
                else
                    split=strsplit(s,'.');
                    name=sprintf('rSE_%s.txt',split{1});
                    [filename,path,~] = uiputfile(name);
                    Rse=string(app.RSEField.Value);
                    fp=fopen([path,filename],'w');
                    fprintf(fp,'%s %s',s,Rse);
                    fclose(fp);
                end
                app.flagsalvataggiorsesingle=1;
            end
            message=sprintf('rSE correttamente salvato nella cartella');
            uialert(app.Progetto_EIMUIFigure,message,'Salvataggio','Icon','info');
        end

        % Callback function
        function BatchModeButtonPushed(app, event)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.Switch.Value %siamo in single passiamo in batch
                % caso 0
                %scompaiono
                app.OriginalImage.Visible='off';
                app.NormalizedImage.Visible='off';
                app.FilevisualizzatoEditField.Visible='off';
                app.FileNormalizzatoEditField.Visible='off';
                app.FileNormalizzatoEditFieldLabel.Visible='off';
                app.FilevisualizzatoEditFieldLabel.Visible='off';
                
                app.ImmagineNormalizzataLabel.Visible='off';
                app.RSEField.Visible='off';
                app.CalcolarSEButton.Visible='off';
                app.PrecedenteButton.Visible='off';
                app.SuccessivaButton.Visible='off';
                app.UITable.Visible='on';
                
                %si disabilitano
                app.SalvataggioImmagineNormalizzataButton.Enable='off';
                app.SalvataggiorSEImmagineNormalizzataButton.Enable='off';
                %app.ButtonGroup.Enable='off'; %%%%%
                app.xlsButton.Enable='off';
                app.txtButton.Enable='off';
                
                %cambiano il testo
                app.SalvataggioImmagineNormalizzataButton.Text='Salvataggio Immagini';
                %app.CaricamentoButton.Text='Caricamento cartella';
                app.ImmagineOriginaleLabel.Text='Immagini Cartella';
                app.NormalizzaButton.Text='Normalizza Cartella';
                
                %caso 0 carichiamo il batch e non il single
                if isempty(app.OriginalImage.ImageSource) && ~isempty(app.storage)
                    app.NormalizzaButton.Enable='on';
                end
                
                %caso 1 abbiamo caricato il single e non il batch
                if ~isempty(app.OriginalImage.ImageSource) && isempty(app.storage)
                    app.NormalizzaButton.Enable='off';
                     %caso 1.1 normalizziamo il single e niente il batch
                    if ~isempty(app.NormalizedImage.ImageSource)
                        app.SalvataggioImmagineNormalizzataButton.Enable='off';
                        app.NormalizzaButton.Enable='off';
                        %caso 1.2 single, abbiamo calcolato anche l'rse
                        if ~(app.RSEField.Value==0)
                            app.SalvataggiorSEImmagineNormalizzataButton.Enable='off';
                            %app.ButtonGroup.Enable='off'; %%%%%
                            app.xlsButton.Enable='off';
                            app.txtButton.Enable='off';
                        end
                    end
                end
                
                % caso 2, abbiamo caricato il single e il batch
                if ~isempty(app.OriginalImage.ImageSource) && ~isempty(app.storage)
                    %caso 2.1 abbiamo normalizzato il single
                    if ~isempty(app.NormalizedImage.ImageSource)
                        app.SalvataggioImmagineNormalizzataButton.Enable='off';
                        app.NormalizzaButton.Enable='on';
                        %caso 2.2 single, abbiamo calcolato anche l'rse 
                        
                        %caso 2.3 single, abbiamo salvato rse
                        %caso 2.4 salviamo immagine
                    end
                end
                
                %caso 3 carichiamo il single e carichiamo e normalizziamo
                %il batch
                if ~isempty(app.OriginalImage.ImageSource) && ~isempty(app.storage) && ~isempty(app.RseStorage)
                    app.SalvataggioImmagineNormalizzataButton.Enable='on';
                    app.SalvataggiorSEImmagineNormalizzataButton.Enable='on';
                    %app.ButtonGroup.Enable='on'; %%%%%
                    app.xlsButton.Enable='on';
                    app.txtButton.Enable='on';
                    app.NormalizzaButton.Enable='off';
                end
                
                %caso 5 solo batch con normalizzazione
                if ~isempty(app.RseStorage) && isempty(app.OriginalImage.ImageSource)
                    app.SalvataggioImmagineNormalizzataButton.Enable='on';
                    app.SalvataggiorSEImmagineNormalizzataButton.Enable='on';
                    %app.ButtonGroup.Enable='on'; %%%%%
                    app.xlsButton.Enable='on';
                    app.txtButton.Enable='on';
                    app.NormalizzaButton.Enable='off';
                end
                
            else %siamo in batch passiamo in single
                %caso 0
                %scompaiono
                app.OriginalImage.Visible='on';
                app.NormalizedImage.Visible='on';
                app.FilevisualizzatoEditField.Visible='on';
                app.FileNormalizzatoEditField.Visible='on';
                app.FileNormalizzatoEditFieldLabel.Visible='on';
                app.FilevisualizzatoEditFieldLabel.Visible='on';
                app.ImmagineOriginaleLabel.Visible='on';
                app.ImmagineNormalizzataLabel.Visible='on';
                app.RSEField.Visible='on';
                app.UITable.Visible='off';
                app.CalcolarSEButton.Visible='on';
                app.PrecedenteButton.Visible='on';
                app.SuccessivaButton.Visible='on';
                
                %app.CaricamentoButton.Text='Caricamento Immagini';
                app.NormalizzaButton.Text='Normalizza immagine';
                app.ImmagineOriginaleLabel.Text='Immagine Originale';
                %caso 0 carichiamo il batch e non il single
                if isempty(app.OriginalImage.ImageSource) && ~isempty(app.storage)
                    app.NormalizzaButton.Enable='off';
                end
                
                %caso 1 abbiamo caricato il single e non il batch
                if ~isempty(app.OriginalImage.ImageSource) && isempty(app.storage)
                    app.NormalizzaButton.Enable='on';
                    %caso 1.1 normalizziamo il single e niente il batch
                    if ~isempty(app.NormalizedImage.ImageSource)
                        app.SalvataggioImmagineNormalizzataButton.Enable='on';
                        app.NormalizzaButton.Enable='off';
                        %caso 1.2 single, abbiamo calcolato anche l'rse
                        if ~(app.RSEField.Value==0)
                            app.SalvataggiorSEImmagineNormalizzataButton.Enable='on';
                            %app.ButtonGroup.Enable='on'; %%%%%
                            app.xlsButton.Enable='on';
                            app.txtButton.Enable='on';
                            %caso 1.3 single, abbiamo salvato l'rse
%                             if app.flagsalvataggiorsesingle==1
%                                 app.SalvataggiorSEImmagineNormalizzataButton.Enable='off';
%                                 app.ButtonGroup.Enable='off';
%                             end
                        end
                        %caso 1.4 single abbiamo salvato img
%                         if app.flagsalvataggiosingleimg==1
%                             app.SalvataggioImmagineNormalizzataButton.Enable='off';
%                         end
                    end
                end
                
                % caso 2, abbiamo caricato il single e il batch
                if ~isempty(app.OriginalImage.ImageSource) && ~isempty(app.storage)
                    %caso 2.1 abbiamo normalizzato il single
                    if ~isempty(app.NormalizedImage.ImageSource)
                        app.SalvataggioImmagineNormalizzataButton.Enable='on';
                        app.NormalizzaButton.Enable='off';
                        %caso 2.2 single, abbiamo calcolato anche l'rse
                        if ~(app.RSEField.Value==0)
                            app.SalvataggiorSEImmagineNormalizzataButton.Enable='on';
                            %app.ButtonGroup.Enable='on'; %%%%%
                            app.xlsButton.Enable='on';
                            app.txtButton.Enable='on';
                            %caso 2.3 salviamo irse
%                             if app.flagsalvataggiorsesingle==1
%                                 app.SalvataggiorSEImmagineNormalizzataButton.Enable='off';
%                                 app.ButtonGroup.Enable='off';
%                             end
                        end
                        %caso 2.4 salviamo immagine
%                         if app.flagsalvataggiosingleimg==1
%                             app.SalvataggioImmagineNormalizzataButton.Enable='off';
%                         end
                        
                    end
                end
                
                %caso 3 carichiamo il single e carichiamo e normalizziamo
                %il batch
                if ~isempty(app.OriginalImage.ImageSource) && ~isempty(app.storage) && ~isempty(app.RseStorage)
                    app.SalvataggioImmagineNormalizzataButton.Enable='off';
                    app.SalvataggiorSEImmagineNormalizzataButton.Enable='off';
                    %app.ButtonGroup.Enable='off'; %%%%%
                    app.xlsButton.Enable='off';
                    app.txtButton.Enable='off';
                    app.NormalizzaButton.Enable='on';
                end
                %caso 4 entrambi normalizzati
                if ~isempty(app.RseStorage) && ~isempty(app.NormalizedImage.ImageSource)
                    app.NormalizzaButton.Enable='off';
                    app.SalvataggioImmagineNormalizzataButton.Enable='on';
                    if ~(app.RSEField.Value==0)
                            app.SalvataggiorSEImmagineNormalizzataButton.Enable='on';
                            %app.ButtonGroup.Enable='on'; %%%%%
                            app.xlsButton.Enable='on';
                            app.txtButton.Enable='on';
                    end
                end
                %caso 5 solo batch con normalizzazione
                if ~isempty(app.RseStorage) && isempty(app.OriginalImage.ImageSource)
                    app.SalvataggioImmagineNormalizzataButton.Enable='off';
                    app.SalvataggiorSEImmagineNormalizzataButton.Enable='off';
                    %app.ButtonGroup.Enable='off'; %%%%%
                    app.xlsButton.Enable='off';
                    app.txtButton.Enable='off';
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Progetto_EIMUIFigure = uifigure('Visible', 'off');
            app.Progetto_EIMUIFigure.Name = 'Progetto_EIM';
            app.OriginalImage = uiimage(app.Progetto_EIMUIFigure);
            app.CaricamentoButton = uibutton(app.Progetto_EIMUIFigure, 'push');
            app.CaricamentoButton.ButtonPushedFcn = createCallbackFcn(app, @CaricamentoButtonPushed, true);
            app.CaricamentoButton.Text = 'Caricamento';
            app.NormalizedImage = uiimage(app.Progetto_EIMUIFigure);
            app.ImmagineOriginaleLabel = uilabel(app.Progetto_EIMUIFigure);
            app.ImmagineOriginaleLabel.Text = 'Immagine Originale';
            app.ImmagineNormalizzataLabel = uilabel(app.Progetto_EIMUIFigure);
            app.ImmagineNormalizzataLabel.Text = 'Immagine Normalizzata';
            app.SuccessivaButton = uibutton(app.Progetto_EIMUIFigure, 'push');
            app.SuccessivaButton.ButtonPushedFcn = createCallbackFcn(app, @SuccessivaButtonPushed, true);
            app.SuccessivaButton.Text = 'Successiva';
            app.PrecedenteButton = uibutton(app.Progetto_EIMUIFigure, 'push');
            app.PrecedenteButton.ButtonPushedFcn = createCallbackFcn(app, @PrecedenteButtonPushed, true);
            app.PrecedenteButton.Text = 'Precedente';
            app.FilevisualizzatoEditFieldLabel = uilabel(app.Progetto_EIMUIFigure);
            app.FilevisualizzatoEditFieldLabel.Text = 'File visualizzato';
            app.FilevisualizzatoEditField = uieditfield(app.Progetto_EIMUIFigure, 'text');
            app.FilevisualizzatoEditField.Editable = 'off';
            app.TargetImage = uiimage(app.Progetto_EIMUIFigure);
            app.ImmagineTargetLabel = uilabel(app.Progetto_EIMUIFigure);
            app.ImmagineTargetLabel.Text = 'Immagine Target';
            app.NormalizzaButton = uibutton(app.Progetto_EIMUIFigure, 'push');
            app.NormalizzaButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizzaButtonPushed, true);
            app.NormalizzaButton.Text = 'Normalizza';
            app.CalcolarSEButton = uibutton(app.Progetto_EIMUIFigure, 'push');
            app.CalcolarSEButton.ButtonPushedFcn = createCallbackFcn(app, @CalcolarSEButtonPushed, true);
            app.CalcolarSEButton.Text = 'Calcola rSE';
            app.RSEField = uieditfield(app.Progetto_EIMUIFigure, 'numeric');
            app.SalvataggioImmagineNormalizzataButton = uibutton(app.Progetto_EIMUIFigure, 'push');
            app.SalvataggioImmagineNormalizzataButton.ButtonPushedFcn = createCallbackFcn(app, @SalvataggioImmagineNormalizzataButtonPushed, true);
            app.SalvataggioImmagineNormalizzataButton.Text = 'Salvataggio Immagine Normalizzata';
            app.FileNormalizzatoEditFieldLabel = uilabel(app.Progetto_EIMUIFigure);
            app.FileNormalizzatoEditFieldLabel.Text = 'File Normalizzato';
            app.FileNormalizzatoEditField = uieditfield(app.Progetto_EIMUIFigure, 'text');
            app.FileNormalizzatoEditField.Editable = 'off';
            app.CaricaTargetButton = uibutton(app.Progetto_EIMUIFigure, 'push');
            app.CaricaTargetButton.ButtonPushedFcn = createCallbackFcn(app, @CaricaTargetButtonPushed, true);
            app.CaricaTargetButton.Text = 'Carica Target';
            app.FileTargetEditFieldLabel = uilabel(app.Progetto_EIMUIFigure);
            app.FileTargetEditFieldLabel.Text = 'File Target';
            app.FileTargetEditField = uieditfield(app.Progetto_EIMUIFigure, 'text');
            app.FileTargetEditField.Editable = 'off';
            app.SalvataggiorSEImmagineNormalizzataButton = uibutton(app.Progetto_EIMUIFigure, 'push');
            app.SalvataggiorSEImmagineNormalizzataButton.ButtonPushedFcn = createCallbackFcn(app, @SalvataggiorSEImmagineNormalizzataButtonPushed, true);
            app.SalvataggiorSEImmagineNormalizzataButton.Text = {'Salvataggio rSE'; 'Immagine Normalizzata'};
            app.Label = uilabel(app.Progetto_EIMUIFigure);
            app.Label.Text = '';
            app.Switch = uiswitch(app.Progetto_EIMUIFigure, 'slider');
            app.Switch.Items = {'Single Mode', 'Batch Mode'};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.ButtonGroup = uibuttongroup(app.Progetto_EIMUIFigure);
            app.txtButton = uiradiobutton(app.ButtonGroup);
            app.txtButton.Text = '.txt';
            app.xlsButton = uiradiobutton(app.ButtonGroup);
            app.xlsButton.Text = '.xls';
            app.xlsButton.Value = true;
            app.UITable = uitable(app.Progetto_EIMUIFigure);
            app.UITable.ColumnName = {'File'; 'Percorso'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.Progetto_EIMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPLICAZIONE_PROGETTO(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Progetto_EIMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Progetto_EIMUIFigure)
        end
    end
end